classdef adohe_fototoko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup2                  matlab.ui.container.TabGroup
        ImageTab                   matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        InputImage                 matlab.ui.control.Image
        OutputImage                matlab.ui.control.Image
        HistogramTab_2             matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        OutputLabel                matlab.ui.control.Label
        InputLabel                 matlab.ui.control.Label
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        SpecificationHistogramTab  matlab.ui.container.Tab
        GridLayout4_2              matlab.ui.container.GridLayout
        OutputLabel_2              matlab.ui.control.Label
        TargetLabel                matlab.ui.control.Label
        SpecUIAxesG                matlab.ui.control.UIAxes
        SpecUIAxesB                matlab.ui.control.UIAxes
        OutSpecUIAxesB             matlab.ui.control.UIAxes
        OutSpecUIAxesG             matlab.ui.control.UIAxes
        OutSpecUIAxesR             matlab.ui.control.UIAxes
        SpecUIAxesR                matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        OpenFileTab                matlab.ui.container.Tab
        GridLayout6_2              matlab.ui.container.GridLayout
        ChooseImageButton          matlab.ui.control.Button
        NoFileSelectedLabel        matlab.ui.control.Label
        HistogramTab               matlab.ui.container.Tab
        GridLayout6                matlab.ui.container.GridLayout
        ProcessButton_3            matlab.ui.control.Button
        BrightnessTab              matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel  matlab.ui.control.Label
        ApplyButton_2              matlab.ui.control.Button
        EnteradanbvaluesarbLabel   matlab.ui.control.Label
        BrightnessB                matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        ProcessButton              matlab.ui.control.Button
        BrightnessA                matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        NegativeTab                matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_2  matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        ProcessButton_2            matlab.ui.control.Button
        LogTransformationTab       matlab.ui.container.Tab
        GridLayout2_2              matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_3  matlab.ui.control.Label
        LogApply                   matlab.ui.control.Button
        Entercvaluesclog1rLabel    matlab.ui.control.Label
        LogProcess                 matlab.ui.control.Button
        LogC                       matlab.ui.control.NumericEditField
        cLabel                     matlab.ui.control.Label
        PowerTransformationTab     matlab.ui.container.Tab
        GridLayout2_3              matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_4  matlab.ui.control.Label
        PowerGamma                 matlab.ui.control.NumericEditField
        cLabel_3                   matlab.ui.control.Label
        LogApply_2                 matlab.ui.control.Button
        EntercandgammavaluescrgammaLabel  matlab.ui.control.Label
        PowerProcess               matlab.ui.control.Button
        PowerC                     matlab.ui.control.NumericEditField
        cLabel_2                   matlab.ui.control.Label
        ContrastStretchingTab      matlab.ui.container.Tab
        GridLayout2_4              matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_5  matlab.ui.control.Label
        LogApply_3                 matlab.ui.control.Button
        StretchingProcess          matlab.ui.control.Button
        EqualizationTab            matlab.ui.container.Tab
        GridLayout7                matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_6  matlab.ui.control.Label
        LogApply_4                 matlab.ui.control.Button
        EqualizationProcess        matlab.ui.control.Button
        SpecificationTab           matlab.ui.container.Tab
        GridLayout7_2              matlab.ui.container.GridLayout
        LakukanProcessterlebihdahulusebelumApplyLabel_7  matlab.ui.control.Label
        LogApply_5                 matlab.ui.control.Button
        SpecImage                  matlab.ui.control.Image
        SpecProcess                matlab.ui.control.Button
        SpecNoFileSelectedLabel    matlab.ui.control.Label
        ChooseTargetImageButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
        SpecTargetImagedata % Description
        SpecTargetFullImagename % Description
    end
    
    methods (Access = private)
        
        function setHistogram(~, imagedata, UIImage, RAxes, GAxes, BAxes)
            % clear histogram
            cla(RAxes);
            cla(GAxes);
            cla(BAxes);
            title(RAxes, '');
            title(GAxes, '');
            title(BAxes, '');

            % handle rgb image
            numChannel = size(imagedata, 3);
            if numChannel == 3
                % Display the image in the ui image component
                UIImage.ImageSource = imagedata;
            
                % Separate the RGB channels
                redChannel = imagedata(:, :, 1);
                greenChannel = imagedata(:, :, 2);
                blueChannel = imagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(RAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(RAxes, 'on');
                bar(GAxes, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(GAxes, 'on');
                bar(BAxes, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(BAxes, 'on');
                
                % Set titles for each axes
                title(RAxes, 'Red Channel Histogram');
                title(GAxes, 'Green Channel Histogram');
                title(BAxes, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(redHistogram)]); 
                ylim(GAxes, [0, max(greenHistogram)]);
                ylim(BAxes, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                xlim(GAxes, [0, 256]);
                xlim(BAxes, [0, 256]);

                % Turn off hold for all axes
                hold(RAxes, 'off');
                hold(GAxes, 'off');
                hold(BAxes, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, imagedata(:, :, 1),imagedata(:, :, 1),imagedata(:, :, 1));
                % Display the image in the uiimage component
                UIImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = imagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(RAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(RAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                
                % Set a title for the axis
                title(RAxes, 'Grayscale Image Histogram');
                hold(RAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end

        function setHistogramInput(app, numChannel)
            
            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            % handle rgb image
            if numChannel == 3
                % Display the image in the ui image component
                app.InputImage.ImageSource = app.Inputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Inputimagedata(:, :, 1);
                greenChannel = app.Inputimagedata(:, :, 2);
                blueChannel = app.Inputimagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                bar(app.UIAxes2, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                bar(app.UIAxes3, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes3, 'on');
                
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(redHistogram)]); 
                ylim(app.UIAxes2, [0, max(greenHistogram)]);
                ylim(app.UIAxes3, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                xlim(app.UIAxes2, [0, 256]);
                xlim(app.UIAxes3, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1));
                % Display the image in the uiimage component
                app.InputImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = app.Inputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(app.UIAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes, 'on');
                
                disp(greyHistogram)
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        
        function setHistogramOutput(app, numChannel)
            % clear histogram
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            title(app.UIAxes4, '');
            title(app.UIAxes5, '');
            title(app.UIAxes6, '');
            
            % handle rgb image
            if numChannel == 3
                % Display the image in the uiimage component
                app.OutputImage.ImageSource = app.Outputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Outputimagedata(:, :, 1);
                greenChannel = app.Outputimagedata(:, :, 2);
                blueChannel = app.Outputimagedata(:, :, 3);
        
                % set value histogram
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);
                
                % calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes4, 'on');
                bar(app.UIAxes5, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes5, 'on');
                bar(app.UIAxes6, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes6, 'on');
    
                % Set titles for each axes
                title(app.UIAxes4, 'Red Channel Histogram');
                title(app.UIAxes5, 'Green Channel Histogram');
                title(app.UIAxes6, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(redHistogram)]); 
                ylim(app.UIAxes5, [0, max(greenHistogram)]);
                ylim(app.UIAxes6, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);
                xlim(app.UIAxes5, [0, 256]);
                xlim(app.UIAxes6, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes4, 'off');
                hold(app.UIAxes5, 'off');
                hold(app.UIAxes6, 'off');

            % handle grayscale image
            elseif numChannel == 1
                % convert grayscale to format rgb to show image
                rgbimage = cat(3, app.Outputimagedata,app.Outputimagedata,app.Outputimagedata);

                % Display the image in the uiimage component
                app.OutputImage.ImageSource = rgbimage;
            
                % grey channel
                greyChannel = app.Outputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes4, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);

                % Set a title for the axis
                title(app.UIAxes4, 'Grayscale Image Histogram');
                hold(app.UIAxes4, 'off');
            end
        end
            

        function setText(app, label)
            % set text label
            app.NoFileSelectedLabel.Text = label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function processBrightness(app, event)
            % Getting data
            a = app.BrightnessA.Value;
            b = app.BrightnessB.Value;
            r = app.Inputimagedata;

            % Doing calculation
            s = a * r + b;

            % Replace values > 255 to 255 and values < 0 to 0
            s(s > 255) = 255;
            s(s < 0) = 0;

            % Set output image
            app.Outputimagedata = s;
            
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ProcessButton_2
        function processNegative(app, event)
            % Doing calculation and set output image
            app.Outputimagedata = 255 - app.Inputimagedata;
            
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ApplyButton, ApplyButton_2, LogApply, 
        % ...and 2 other components
        function apply(app, event)
            % Apply so that output image -> input image
            app.Inputimagedata = app.Outputimagedata;

            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Callback function: ChooseImageButton, OpenMenu
        function chooseFileHandler(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.SpecImage component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.Fullimagename = fullFilePath;
            app.Inputimagedata = bmpImage; 
            app.Outputimagedata = bmpImage;
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
          
            numChannels = size(bmpImage, 3);

            % set all variable
            app.setText(file)
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: EqualizationProcess
        function processEqualization(app, event)
            % [N, M] = size(app.Inputimagedata);
            % 
            % % Hitung histogram citra
            % HistInput = myhist(app.Inputimagedata, true);
            % HistOutput = zeros(256, 1);
            % 
            % app.Outputimagedata = app.Inputimagedata;
            % 
            % % Hitung histogram citra
            % sum = 0.0;
            % for i = 1:256
            %     sum = sum + HistInput(i);
            %     HistOutput(i) = floor(255 * sum);
            % end
            % 
            % % Update citra sesuai histogram hasil perataan
            % for i = 1:N
            %     for j = 1:M
            %         app.Outputimagedata(i, j) = HistOutput(app.Inputimagedata(i, j) + 1);
            %     end
            % end
            
            result = histogramEqualization(app.Inputimagedata);

            app.Outputimagedata = result;
            numChannels = size(app.Outputimagedata, 3);
        
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: LogProcess
        function processLog(app, event)
            % Getting data
            c = app.LogC.Value;
            r = im2double(app.Inputimagedata);  % convert image data to double

            % Doing calculation
            s = c * log(1 + r);

            % Replace values > 1 to 1 and values < 0 to 0
            s(s > 1) = 1;
            s(s < 0) = 0;
            
            % Set output image (and map resulting data to uint8)
            app.Outputimagedata = im2uint8(s);

            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: PowerProcess
        function processPower(app, event)
            % Getting data
            c = app.PowerC.Value;
            gamma = app.PowerGamma.Value;
            r = im2double(app.Inputimagedata);  % convert image data to double

            % Doing calculation
            s = c * (r .^ gamma);

            % Replace values > 1 to 1 and values < 0 to 0
            s(s > 1) = 1;
            s(s < 0) = 0;

            % Set output image (and map resulting data to uint8)
            app.Outputimagedata = im2uint8(s);

            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: StretchingProcess
        function processStretching(app, event)
            numChannels = size(app.Inputimagedata, 3);
            if numChannels == 1
                rmin = min(min(app.Inputimagedata));
                rmax = max(max(app.Inputimagedata));
                app.Outputimagedata = (app.Inputimagedata - rmin) .* (255/ (rmax - rmin));
            elseif numChannels == 3
                app.Outputimagedata = app.Inputimagedata;
                rmin = [0, 0, 0];
                rmax = [0, 0, 0];
                for i = 1:numChannels
                    rmin(i) = min(min(app.Inputimagedata(:,:,i)));
                    rmax(i) = max(max(app.Inputimagedata(:,:,i)));
                end
                rmin = max(rmin);
                rmax = min(rmax);
                app.Outputimagedata = (app.Inputimagedata - rmin) .* (255/ (rmax - rmin));
            else
                msgbox('Unsupported image format', 'Error', 'error');
            end

            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ProcessButton_3
        function processHistogram(app, event)
            numChannels = size(app.Inputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ChooseTargetImageButton
        function specificationChooseTarget(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.SpecNoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.SpecImage component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.SpecTargetFullImagename = fullFilePath;
            app.SpecTargetImagedata = bmpImage; 

            % set text
            app.SpecNoFileSelectedLabel.Text = file;
            % set target 
            app.setHistogram(app.SpecTargetImagedata, app.SpecImage, app.SpecUIAxesR, app.SpecUIAxesG, app.SpecUIAxesB);
        end

        % Button pushed function: SpecProcess
        function processSpecification(app, event)
            % Create input and target histogram
            inputHist = myhist(app.Inputimagedata, true);
            targetHist = myhist(app.SpecTargetImagedata, true);
            
            % Calculate input equalization
            inputEq = inputHist;
            sum = 0.0;
            for i = 1:256
                sum = sum + inputHist(i);
                inputEq(i) = floor(255 * sum);
            end

            % Calculate target equalization
            targetEq = targetHist;
            sum = 0.0;
            for i = 1:256
                sum = sum + targetHist(i);
                targetEq(i) = floor(255 * sum);
            end

            % Do inverse transformation
            invMapping = zeros(256);
            for i = 1:256
                minval = abs(inputEq(i) - targetEq(1));
                minj = 0;
                for j = 1:256
                    if (abs(inputEq(i) - targetEq(j)) < minval)
                        minval = abs(inputEq(i) - targetEq(j));
                        minj = j;
                    end
                end
                invMapping(i) = minj;
            end

            % Apply the inverse to input image, resulting output image
            app.Outputimagedata = app.Inputimagedata;
            [M, N] = size(app.Inputimagedata);
            for i = 1:M
                for j = 1:N
                    app.Outputimagedata(i,j) = invMapping(app.Inputimagedata(i,j) + 1);
                end
            end
            
            % Display the image and histogram
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogram(app.Outputimagedata, app.OutputImage, app.OutSpecUIAxesR, app.OutSpecUIAxesG, app.OutSpecUIAxesB);
            app.setHistogramOutput(numChannels);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Tooltip = {'Histogram'};
            app.OpenFileTab = uitab(app.TabGroup);
            app.OpenFileTab.Title = 'Open File';
            app.GridLayout6_2 = uigridlayout(app.OpenFileTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6_2);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseImageButton = uibutton(app.GridLayout6_2, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramTab);
            app.ProcessButton_3 = uibutton(app.GridLayout6, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @processHistogram, true);
            app.ProcessButton_3.Text = 'Process';
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Tooltip = {'Brightness'};
            app.BrightnessTab.Title = 'Brightness';
            app.GridLayout2 = uigridlayout(app.BrightnessTab);
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.BrightnessA = uieditfield(app.GridLayout2, 'numeric');
            app.BrightnessA.Value = 1;
            app.ProcessButton = uibutton(app.GridLayout2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processBrightness, true);
            app.ProcessButton.Text = 'Process';
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.BrightnessB = uieditfield(app.GridLayout2, 'numeric');
            app.EnteradanbvaluesarbLabel = uilabel(app.GridLayout2);
            app.EnteradanbvaluesarbLabel.Text = 'Enter a dan b value (s = ar + b)';
            app.ApplyButton_2 = uibutton(app.GridLayout2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_2.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel = uilabel(app.GridLayout2);
            app.LakukanProcessterlebihdahulusebelumApplyLabel.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.NegativeTab = uitab(app.TabGroup);
            app.NegativeTab.Tooltip = {'Negative'};
            app.NegativeTab.Title = 'Negative';
            app.GridLayout3 = uigridlayout(app.NegativeTab);
            app.ProcessButton_2 = uibutton(app.GridLayout3, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @processNegative, true);
            app.ProcessButton_2.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel_2 = uilabel(app.GridLayout3);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_2.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.LogTransformationTab = uitab(app.TabGroup);
            app.LogTransformationTab.Tooltip = {'Log Transformation'};
            app.LogTransformationTab.Title = 'Log Transformation';
            app.GridLayout2_2 = uigridlayout(app.LogTransformationTab);
            app.cLabel = uilabel(app.GridLayout2_2);
            app.cLabel.Text = 'c';
            app.LogC = uieditfield(app.GridLayout2_2, 'numeric');
            app.LogC.Value = 1;
            app.LogProcess = uibutton(app.GridLayout2_2, 'push');
            app.LogProcess.ButtonPushedFcn = createCallbackFcn(app, @processLog, true);
            app.LogProcess.Text = 'Process';
            app.Entercvaluesclog1rLabel = uilabel(app.GridLayout2_2);
            app.Entercvaluesclog1rLabel.Text = 'Enter c value (s = c log(1 + r))';
            app.LogApply = uibutton(app.GridLayout2_2, 'push');
            app.LogApply.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.LogApply.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel_3 = uilabel(app.GridLayout2_2);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_3.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.PowerTransformationTab = uitab(app.TabGroup);
            app.PowerTransformationTab.Tooltip = {'Power Transformation'};
            app.PowerTransformationTab.Title = 'Power Transformation';
            app.GridLayout2_3 = uigridlayout(app.PowerTransformationTab);
            app.cLabel_2 = uilabel(app.GridLayout2_3);
            app.cLabel_2.Text = 'c';
            app.PowerC = uieditfield(app.GridLayout2_3, 'numeric');
            app.PowerC.Value = 1;
            app.PowerProcess = uibutton(app.GridLayout2_3, 'push');
            app.PowerProcess.ButtonPushedFcn = createCallbackFcn(app, @processPower, true);
            app.PowerProcess.Text = 'Process';
            app.EntercandgammavaluescrgammaLabel = uilabel(app.GridLayout2_3);
            app.EntercandgammavaluescrgammaLabel.Text = 'Enter c and gamma value (s = cr^gamma)';
            app.LogApply_2 = uibutton(app.GridLayout2_3, 'push');
            app.LogApply_2.Text = 'Apply';
            app.cLabel_3 = uilabel(app.GridLayout2_3);
            app.cLabel_3.Text = 'gamma';
            app.PowerGamma = uieditfield(app.GridLayout2_3, 'numeric');
            app.PowerGamma.Value = 1;
            app.LakukanProcessterlebihdahulusebelumApplyLabel_4 = uilabel(app.GridLayout2_3);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_4.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.ContrastStretchingTab = uitab(app.TabGroup);
            app.ContrastStretchingTab.Tooltip = {'Contrast Stretching'};
            app.ContrastStretchingTab.Title = 'Contrast Stretching';
            app.GridLayout2_4 = uigridlayout(app.ContrastStretchingTab);
            app.StretchingProcess = uibutton(app.GridLayout2_4, 'push');
            app.StretchingProcess.ButtonPushedFcn = createCallbackFcn(app, @processStretching, true);
            app.StretchingProcess.Text = 'Process';
            app.LogApply_3 = uibutton(app.GridLayout2_4, 'push');
            app.LogApply_3.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel_5 = uilabel(app.GridLayout2_4);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_5.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.EqualizationTab = uitab(app.TabGroup);
            app.EqualizationTab.Tooltip = {'Equalization'};
            app.EqualizationTab.Title = 'Equalization';
            app.GridLayout7 = uigridlayout(app.EqualizationTab);
            app.EqualizationProcess = uibutton(app.GridLayout7, 'push');
            app.EqualizationProcess.ButtonPushedFcn = createCallbackFcn(app, @processEqualization, true);
            app.EqualizationProcess.Text = 'Process';
            app.LogApply_4 = uibutton(app.GridLayout7, 'push');
            app.LogApply_4.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.LogApply_4.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel_6 = uilabel(app.GridLayout7);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_6.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.SpecificationTab = uitab(app.TabGroup);
            app.SpecificationTab.Tooltip = {'Specification'};
            app.SpecificationTab.Title = 'Specification';
            app.GridLayout7_2 = uigridlayout(app.SpecificationTab);
            app.ChooseTargetImageButton = uibutton(app.GridLayout7_2, 'push');
            app.ChooseTargetImageButton.ButtonPushedFcn = createCallbackFcn(app, @specificationChooseTarget, true);
            app.ChooseTargetImageButton.Text = 'Choose Target Image';
            app.SpecNoFileSelectedLabel = uilabel(app.GridLayout7_2);
            app.SpecNoFileSelectedLabel.Text = 'No File Selected';
            app.SpecProcess = uibutton(app.GridLayout7_2, 'push');
            app.SpecProcess.ButtonPushedFcn = createCallbackFcn(app, @processSpecification, true);
            app.SpecProcess.Text = 'Process';
            app.SpecImage = uiimage(app.GridLayout7_2);
            app.LogApply_5 = uibutton(app.GridLayout7_2, 'push');
            app.LogApply_5.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.LogApply_5.Text = 'Apply';
            app.LakukanProcessterlebihdahulusebelumApplyLabel_7 = uilabel(app.GridLayout7_2);
            app.LakukanProcessterlebihdahulusebelumApplyLabel_7.Text = '* Lakukan Process terlebih dahulu sebelum Apply';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.GridLayout5 = uigridlayout(app.ImageTab);
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            app.SpecificationHistogramTab = uitab(app.TabGroup2);
            app.SpecificationHistogramTab.Title = 'Specification Histogram';
            app.GridLayout4_2 = uigridlayout(app.SpecificationHistogramTab);
            app.SpecUIAxesR = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesR, 'Title')
            xlabel(app.SpecUIAxesR, 'X')
            ylabel(app.SpecUIAxesR, 'Y')
            zlabel(app.SpecUIAxesR, 'Z')
            app.OutSpecUIAxesR = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesR, 'Title')
            xlabel(app.OutSpecUIAxesR, 'X')
            ylabel(app.OutSpecUIAxesR, 'Y')
            zlabel(app.OutSpecUIAxesR, 'Z')
            app.OutSpecUIAxesG = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesG, 'Title')
            xlabel(app.OutSpecUIAxesG, 'X')
            ylabel(app.OutSpecUIAxesG, 'Y')
            zlabel(app.OutSpecUIAxesG, 'Z')
            app.OutSpecUIAxesB = uiaxes(app.GridLayout4_2);
            title(app.OutSpecUIAxesB, 'Title')
            xlabel(app.OutSpecUIAxesB, 'X')
            ylabel(app.OutSpecUIAxesB, 'Y')
            zlabel(app.OutSpecUIAxesB, 'Z')
            app.SpecUIAxesB = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesB, 'Title')
            xlabel(app.SpecUIAxesB, 'X')
            ylabel(app.SpecUIAxesB, 'Y')
            zlabel(app.SpecUIAxesB, 'Z')
            app.SpecUIAxesG = uiaxes(app.GridLayout4_2);
            title(app.SpecUIAxesG, 'Title')
            xlabel(app.SpecUIAxesG, 'X')
            ylabel(app.SpecUIAxesG, 'Y')
            zlabel(app.SpecUIAxesG, 'Z')
            app.TargetLabel = uilabel(app.GridLayout4_2);
            app.TargetLabel.Text = 'Target';
            app.OutputLabel_2 = uilabel(app.GridLayout4_2);
            app.OutputLabel_2.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end