classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NoFileSelectedLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
        ChooseFileButton     matlab.ui.control.Button
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file,path] = uigetfile("*.*", 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end

            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);

            app.NoFileSelectedLabel.Text = file;
            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.Image component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
            
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
            % Display the PNG image in the uiimage component
            app.Image.ImageSource = pngFilePath;
            
            numChannels = size(bmpImage, 3);

            if numChannels == 3
                % Separate the RGB channels
                redChannel = bmpImage(:, :, 1);
                greenChannel = bmpImage(:, :, 2);
                blueChannel = bmpImage(:, :, 3);
        
                % Create histograms for each channel
                histogram(app.UIAxes, redChannel(:), 256, 'FaceColor', 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                histogram(app.UIAxes2, greenChannel(:), 256, 'FaceColor', 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                histogram(app.UIAxes3, blueChannel(:), 256, 'FaceColor', 'b'); % Blue channel histogram
    
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
    
                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
                
            elseif numChannels == 1
                % Create a histogram for the grayscale image
                histogram(app.UIAxes, bmpImage(:), 256, 'FaceColor', 'k'); % Black (gray) channel histogram
        
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
            else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.Image = uiimage(app.UIFigure);
            app.NoFileSelectedLabel = uilabel(app.UIFigure);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end